classdef ZeroCF_DERopt_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DEROptimizerTab              matlab.ui.container.Tab
        PlotElectricLoads            matlab.ui.control.UIAxes
        PlotElectricSources          matlab.ui.control.UIAxes
        IntialCO2ReductionPathLabel  matlab.ui.control.Label
        StartValueEditFieldLabel     matlab.ui.control.Label
        StartValueEditField          matlab.ui.control.NumericEditField
        FinishValueEditFieldLabel    matlab.ui.control.Label
        FinishValueEditField         matlab.ui.control.NumericEditField
        StepValueEditFieldLabel      matlab.ui.control.Label
        StepValueEditField           matlab.ui.control.NumericEditField
        SelectFacilityDropDownLabel  matlab.ui.control.Label
        SelectFacilityDropDown       matlab.ui.control.DropDown
        StartOptimizationButton      matlab.ui.control.Button
        NumberofoptimizationloopsEditFieldLabel  matlab.ui.control.Label
        NumberofoptimizationloopsEditField  matlab.ui.control.NumericEditField
        CO2ReductionSliderLabel      matlab.ui.control.Label
        CO2ReductionSlider           matlab.ui.control.Slider
        OptimizationAnalysisTab      matlab.ui.container.Tab
        PlotCapitalCost              matlab.ui.control.UIAxes
        PlotCostOfCO2                matlab.ui.control.UIAxes
        PlotLCOE                     matlab.ui.control.UIAxes
        ResultsLabel                 matlab.ui.control.Label
        ConfigurationTab             matlab.ui.container.Tab
        Selectwhichmonthsfrom2018toincludeinthecalculationLabel  matlab.ui.control.Label
        JanuaryCheckBox              matlab.ui.control.CheckBox
        FebruaryCheckBox             matlab.ui.control.CheckBox
        MarchCheckBox                matlab.ui.control.CheckBox
        AprilCheckBox                matlab.ui.control.CheckBox
        MayCheckBox                  matlab.ui.control.CheckBox
        JuneCheckBox                 matlab.ui.control.CheckBox
        JulyCheckBox                 matlab.ui.control.CheckBox
        AugustCheckBox               matlab.ui.control.CheckBox
        SeptemberCheckBox            matlab.ui.control.CheckBox
        OctoberCheckBox              matlab.ui.control.CheckBox
        NovemberCheckBox             matlab.ui.control.CheckBox
        DecemberCheckBox             matlab.ui.control.CheckBox
        FilesLocationDropDownLabel   matlab.ui.control.Label
        FilesLocationDropDown        matlab.ui.control.DropDown
        H2ElectrolizerLabel          matlab.ui.control.Label
        SwFuelCellToggle             matlab.ui.control.Switch
        H2CostEditFieldLabel         matlab.ui.control.Label
        H2CostEditField              matlab.ui.control.EditField
        H2ElectrolizerLabel_2        matlab.ui.control.Label
        SwLDGToggle                  matlab.ui.control.Switch
        H2ElectrolizerLabel_3        matlab.ui.control.Label
        SwLPVToggle                  matlab.ui.control.Switch
        H2ElectrolizerLabel_4        matlab.ui.control.Label
        SwLEESToggle                 matlab.ui.control.Switch
        KgLabel                      matlab.ui.control.Label
        ZEROCARBONFUTUREDistributedEnergyResourcesOptimizerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)

        derOpt

    end

    methods (Access = private)
        
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        function UpdateAllVariables(app)

            monthSel = [];

            if app.JanuaryCheckBox.Value == true
                monthSel = [monthSel 1];
            end
            if app.FebruaryCheckBox.Value == true
                monthSel = [monthSel 2];
            end
            if app.MarchCheckBox.Value == true
                monthSel = [monthSel 3];
            end
            if app.AprilCheckBox.Value == true
                monthSel = [monthSel 4];
            end            
            if app.MayCheckBox.Value == true
                monthSel = [monthSel 5];
            end
            if app.JuneCheckBox.Value == true
                monthSel = [monthSel 6];
            end
            if app.JulyCheckBox.Value == true
                monthSel = [monthSel 7];
            end
            if app.AugustCheckBox.Value == true
                monthSel = [monthSel 8];
            end
            if app.SeptemberCheckBox.Value == true
                monthSel = [monthSel 9];
            end
            if app.OctoberCheckBox.Value == true
                monthSel = [monthSel 10];
            end
            if app.NovemberCheckBox.Value == true
                monthSel = [monthSel 11];
            end
            if app.DecemberCheckBox.Value == true
                monthSel = [monthSel 12];
            end

            app.derOpt.SetMonthsSelection(monthSel);

            app.derOpt.SetRunningEnv(str2double(app.FilesLocationDropDown.Value));

            % Check toggles
            if strcmpi(app.SwFuelCellToggle.Value,'On') == 1
                app.derOpt.cfgManager.fuel_cell_binary_on = 1;
            else
                app.derOpt.cfgManager.fuel_cell_binary_on = 0;
            end

            app.derOpt.cfgManager.h2_cost = str2double(app.H2CostEditField.Value);

            if strcmpi(app.SwLDGToggle.Value,'On') == 1
                app.derOpt.cfgManager.ldg_on = 1;
            else
                app.derOpt.cfgManager.ldg_on = 0;
            end

            if strcmpi(app.SwLPVToggle.Value,'On') == 1
                app.derOpt.cfgManager.lpv_on = 1;
            else
                app.derOpt.cfgManager.lpv_on = 0;
            end

            if strcmpi(app.SwLEESToggle.Value,'On') == 1
                app.derOpt.cfgManager.lees_on = 1;
            else
                app.derOpt.cfgManager.lees_on = 0;
            end

        end

        %------------------------------------------------------------------
        %------------------------------------------------------------------

        function ValidateInitialPathCO2Reduction(app)

            app.NumberofoptimizationloopsEditField.Value = app.derOpt.CalculateInitialPathCO2Reduction(app.StartValueEditField.Value,...
                app.FinishValueEditField.Value, app.StepValueEditField.Value);

            % Adjust slider
            app.CO2ReductionSlider.Limits = [app.StartValueEditField.Value app.FinishValueEditField.Value];
            app.CO2ReductionSlider.MajorTicks = app.derOpt.GetCO2ReductionPercentaje();

        end

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.derOpt = CDEROptimizer();

            % Define default values for all controls
            %app.JanuaryCheckBox.Value = true;
            %app.AprilCheckBox.Value = true;
            app.JulyCheckBox.Value = true;
            %app.OctoberCheckBox.Value = true;

            app.H2CostEditField.Value = num2str(app.derOpt.cfgManager.h2_cost);


            % Check toggles
            if app.derOpt.cfgManager.fuel_cell_binary_on == 1
                app.SwFuelCellToggle.Value = 'On';
            else
                app.SwFuelCellToggle.Value = 'Off';
            end

            if app.derOpt.cfgManager.ldg_on == 1
                app.SwLDGToggle.Value = 'On';
            else
                app.SwLDGToggle.Value = 'Off';
            end

            if app.derOpt.cfgManager.lpv_on == 1
                app.SwLPVToggle.Value = 'On';
            else
                app.SwLPVToggle.Value = 'Off';
            end


            if app.derOpt.cfgManager.lees_on == 1
                app.SwLEESToggle.Value = 'On';
            else
                app.SwLEESToggle.Value = 'Off';
            end


            app.FilesLocationDropDown.Value = num2str(app.derOpt.GetEnvironmentIndex());

            app.PlotElectricLoads.Visible = false;
            app.PlotElectricSources.Visible = false;
            app.PlotLCOE.Visible = false;
            app.PlotCostOfCO2.Visible = false;
            app.PlotCapitalCost.Visible = false;
            app.ResultsLabel.Visible = false;

            app.CO2ReductionSlider.Visible = false;
            app.CO2ReductionSliderLabel.Visible = false;

        end

        % Button pushed function: StartOptimizationButton
        function StartOptimizationButtonPushed(app, event)
            
            UpdateAllVariables(app)

            if app.derOpt.IsMonthSelectionEmpty()

                msgbox("No months are select to format the Building Data!","","warn")

            else

                % Validate all inputs
                app.ValidateInitialPathCO2Reduction();

                if app.NumberofoptimizationloopsEditField.Value <= 10

                    clc

                    app.CO2ReductionSlider.Value = app.derOpt.GetMaxCO2ReductionPercentaje();

                    app.derOpt.Optimize(app.UIFigure, app.PlotElectricSources, app.PlotElectricLoads);

                    app.derOpt.PlotOtherResults(app.PlotLCOE, app.PlotCostOfCO2, app.PlotCapitalCost);

                    app.ResultsLabel.Visible = true;
                    app.CO2ReductionSlider.Visible = true;
                    app.CO2ReductionSliderLabel.Visible = true;

                else
                    msgbox("Too many optimization loops. Please, review START, FINISH and STEP values","","warn")
                end

            end

        end

        % Value changed function: FinishValueEditField, 
        % StartValueEditField, StepValueEditField
        function StartValueEditFieldValueChanged(app, event)
            
            app.ValidateInitialPathCO2Reduction();            
            
        end

        % Value changed function: CO2ReductionSlider
        function CO2ReductionSliderValueChanged(app, event)

            value = app.CO2ReductionSlider.Value;

            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);

            % Update Plots
            if app.derOpt.OptimizationResultsAvailable()

                app.derOpt.UpdatePlots(app.derOpt.FindPlotIndex(value), app.PlotElectricSources, app.PlotElectricLoads)

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DEROptimizerTab = uitab(app.TabGroup);
            app.DEROptimizerTab.Title = 'DER Optimizer';
            app.PlotElectricLoads = uiaxes(app.DEROptimizerTab);
            title(app.PlotElectricLoads, 'Title')
            xlabel(app.PlotElectricLoads, '')
            ylabel(app.PlotElectricLoads, 'Y')
            zlabel(app.PlotElectricLoads, 'Z')
            app.PlotElectricSources = uiaxes(app.DEROptimizerTab);
            title(app.PlotElectricSources, 'Title')
            xlabel(app.PlotElectricSources, '')
            ylabel(app.PlotElectricSources, 'Y')
            zlabel(app.PlotElectricSources, 'Z')
            app.IntialCO2ReductionPathLabel = uilabel(app.DEROptimizerTab);
            app.IntialCO2ReductionPathLabel.Text = 'Intial CO2 Reduction Path:';
            app.StartValueEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.StartValueEditFieldLabel.Text = 'Start Value [%]';
            app.StartValueEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.StartValueEditField.Limits = [0 90];
            app.StartValueEditField.ValueDisplayFormat = '%.0f';
            app.StartValueEditField.ValueChangedFcn = createCallbackFcn(app, @StartValueEditFieldValueChanged, true);
            app.FinishValueEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.FinishValueEditFieldLabel.Text = 'Finish Value [%]';
            app.FinishValueEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.FinishValueEditField.Limits = [10 90];
            app.FinishValueEditField.ValueDisplayFormat = '%.0f';
            app.FinishValueEditField.ValueChangedFcn = createCallbackFcn(app, @StartValueEditFieldValueChanged, true);
            app.FinishValueEditField.Value = 50;
            app.StepValueEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.StepValueEditFieldLabel.Text = 'Step Value [%]';
            app.StepValueEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.StepValueEditField.Limits = [1 99];
            app.StepValueEditField.ValueDisplayFormat = '%.0f';
            app.StepValueEditField.ValueChangedFcn = createCallbackFcn(app, @StartValueEditFieldValueChanged, true);
            app.StepValueEditField.Value = 25;
            app.SelectFacilityDropDownLabel = uilabel(app.DEROptimizerTab);
            app.SelectFacilityDropDownLabel.Text = 'Select Facility';
            app.SelectFacilityDropDown = uidropdown(app.DEROptimizerTab);
            app.SelectFacilityDropDown.Items = {'UC Irvine Labs', '0CF Test Facility 1', '0CF Test Facility 2'};
            app.SelectFacilityDropDown.ItemsData = {'1', '2', '3'};
            app.SelectFacilityDropDown.Value = '1';
            app.StartOptimizationButton = uibutton(app.DEROptimizerTab, 'push');
            app.StartOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartOptimizationButtonPushed, true);
            app.StartOptimizationButton.Text = 'Start Optimization';
            app.NumberofoptimizationloopsEditFieldLabel = uilabel(app.DEROptimizerTab);
            app.NumberofoptimizationloopsEditFieldLabel.Text = 'Number of optimization loops';
            app.NumberofoptimizationloopsEditField = uieditfield(app.DEROptimizerTab, 'numeric');
            app.NumberofoptimizationloopsEditField.Editable = 'off';
            app.NumberofoptimizationloopsEditField.Value = 3;
            app.CO2ReductionSliderLabel = uilabel(app.DEROptimizerTab);
            app.CO2ReductionSliderLabel.Text = 'CO2 Reduction';
            app.CO2ReductionSlider = uislider(app.DEROptimizerTab);
            app.CO2ReductionSlider.ValueChangedFcn = createCallbackFcn(app, @CO2ReductionSliderValueChanged, true);
            app.CO2ReductionSlider.MinorTicks = [];
            app.OptimizationAnalysisTab = uitab(app.TabGroup);
            app.OptimizationAnalysisTab.Title = 'Optimization Analysis';
            app.PlotCapitalCost = uiaxes(app.OptimizationAnalysisTab);
            title(app.PlotCapitalCost, 'Title')
            xlabel(app.PlotCapitalCost, '')
            ylabel(app.PlotCapitalCost, 'Y')
            zlabel(app.PlotCapitalCost, 'Z')
            app.PlotCostOfCO2 = uiaxes(app.OptimizationAnalysisTab);
            title(app.PlotCostOfCO2, 'Title')
            xlabel(app.PlotCostOfCO2, '')
            ylabel(app.PlotCostOfCO2, 'Y')
            zlabel(app.PlotCostOfCO2, 'Z')
            app.PlotLCOE = uiaxes(app.OptimizationAnalysisTab);
            title(app.PlotLCOE, 'Title')
            xlabel(app.PlotLCOE, '')
            ylabel(app.PlotLCOE, 'Y')
            zlabel(app.PlotLCOE, 'Z')
            app.ResultsLabel = uilabel(app.OptimizationAnalysisTab);
            app.ResultsLabel.Text = 'NOTE: average discounted costs over 10 years';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.Selectwhichmonthsfrom2018toincludeinthecalculationLabel = uilabel(app.ConfigurationTab);
            app.Selectwhichmonthsfrom2018toincludeinthecalculationLabel.Text = 'Select which months from 2018 to include in the calculation:';
            app.JanuaryCheckBox = uicheckbox(app.ConfigurationTab);
            app.JanuaryCheckBox.Text = 'January';
            app.FebruaryCheckBox = uicheckbox(app.ConfigurationTab);
            app.FebruaryCheckBox.Text = 'February';
            app.MarchCheckBox = uicheckbox(app.ConfigurationTab);
            app.MarchCheckBox.Text = 'March';
            app.AprilCheckBox = uicheckbox(app.ConfigurationTab);
            app.AprilCheckBox.Text = 'April';
            app.MayCheckBox = uicheckbox(app.ConfigurationTab);
            app.MayCheckBox.Text = 'May';
            app.JuneCheckBox = uicheckbox(app.ConfigurationTab);
            app.JuneCheckBox.Text = 'June';
            app.JulyCheckBox = uicheckbox(app.ConfigurationTab);
            app.JulyCheckBox.Text = 'July';
            app.AugustCheckBox = uicheckbox(app.ConfigurationTab);
            app.AugustCheckBox.Text = 'August';
            app.SeptemberCheckBox = uicheckbox(app.ConfigurationTab);
            app.SeptemberCheckBox.Text = 'September';
            app.OctoberCheckBox = uicheckbox(app.ConfigurationTab);
            app.OctoberCheckBox.Text = 'October';
            app.NovemberCheckBox = uicheckbox(app.ConfigurationTab);
            app.NovemberCheckBox.Text = 'November';
            app.DecemberCheckBox = uicheckbox(app.ConfigurationTab);
            app.DecemberCheckBox.Text = 'December';
            app.FilesLocationDropDownLabel = uilabel(app.ConfigurationTab);
            app.FilesLocationDropDownLabel.Text = 'Files Location';
            app.FilesLocationDropDown = uidropdown(app.ConfigurationTab);
            app.FilesLocationDropDown.Items = {'Robert''s PC', 'Roman''s Laptop', 'Roman''s Desktop', 'Bruce''s PC'};
            app.FilesLocationDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FilesLocationDropDown.Value = '1';
            app.H2ElectrolizerLabel = uilabel(app.ConfigurationTab);
            app.H2ElectrolizerLabel.Text = 'H2 Fuel Cell';
            app.SwFuelCellToggle = uiswitch(app.ConfigurationTab, 'slider');
            app.H2CostEditFieldLabel = uilabel(app.ConfigurationTab);
            app.H2CostEditFieldLabel.Text = 'H2 Cost';
            app.H2CostEditField = uieditfield(app.ConfigurationTab, 'text');
            app.H2CostEditField.Value = '8.0';
            app.H2ElectrolizerLabel_2 = uilabel(app.ConfigurationTab);
            app.H2ElectrolizerLabel_2.Text = 'LDG';
            app.SwLDGToggle = uiswitch(app.ConfigurationTab, 'slider');
            app.H2ElectrolizerLabel_3 = uilabel(app.ConfigurationTab);
            app.H2ElectrolizerLabel_3.Text = 'LPV';
            app.SwLPVToggle = uiswitch(app.ConfigurationTab, 'slider');
            app.H2ElectrolizerLabel_4 = uilabel(app.ConfigurationTab);
            app.H2ElectrolizerLabel_4.Text = 'LEES';
            app.SwLEESToggle = uiswitch(app.ConfigurationTab, 'slider');
            app.KgLabel = uilabel(app.ConfigurationTab);
            app.KgLabel.Text = '$/Kg';
            app.ZEROCARBONFUTUREDistributedEnergyResourcesOptimizerLabel = uilabel(app.UIFigure);
            app.ZEROCARBONFUTUREDistributedEnergyResourcesOptimizerLabel.Text = 'ZERO CARBON FUTURE - Distributed Energy Resources Optimizer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZeroCF_DERopt_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end