classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputDataAndGraphs           matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Tab2                          matlab.ui.container.Tab
        UCIDataLoadedLamp             matlab.ui.control.Lamp
        UCIDataLoadedLampLabel        matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        ParameterConfigurationLabel   matlab.ui.control.Label
        OutputInfoTextArea            matlab.ui.control.TextArea
        OutputInfoTextAreaLabel       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        AppEnvironmentTab             matlab.ui.container.Tab
        ChkRomanPc                    matlab.ui.control.CheckBox
        DataFilteringTab              matlab.ui.container.Tab
        DecemberCheckBox              matlab.ui.control.CheckBox
        NovemberCheckBox              matlab.ui.control.CheckBox
        OctoberCheckBox               matlab.ui.control.CheckBox
        SeptemberCheckBox             matlab.ui.control.CheckBox
        AugustCheckBox                matlab.ui.control.CheckBox
        JulyCheckBox                  matlab.ui.control.CheckBox
        JuneCheckBox                  matlab.ui.control.CheckBox
        MayCheckBox                   matlab.ui.control.CheckBox
        AprilCheckBox                 matlab.ui.control.CheckBox
        MarchCheckBox                 matlab.ui.control.CheckBox
        FebruaryCheckBox              matlab.ui.control.CheckBox
        JanuaryCheckBox               matlab.ui.control.CheckBox
        SelectwhichmonthstoincludeinthecalculationLabel  matlab.ui.control.Label
        TechnologiesConfigurationTab  matlab.ui.container.Tab
        StartOptimizationButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        cfg_roman_pc = false;
        cfg_month_idx = [];
        cfg_year_idx = 2018;
    end

    methods (Access = private)
        
        function UpdateAllVariables(app)
            
            if app.ChkRomanPc.Value == true
                app.cfg_roman_pc = true;
            end

            %--------------------------------------------------------------
            % Build cfg_month_idx array
            app.cfg_month_idx = [];

            if app.JanuaryCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 1];
            end
            if app.FebruaryCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 2];
            end
            if app.MarchCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 3];
            end
            if app.AprilCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 4];
            end            
            if app.MayCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 5];
            end
            if app.JuneCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 6];
            end
            if app.JulyCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 7];
            end
            if app.AugustCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 9];
            end
            if app.SeptemberCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 9];
            end
            if app.OctoberCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 10];
            end
            if app.NovemberCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 11];
            end
            if app.DecemberCheckBox.Value == true
                app.cfg_month_idx = [app.cfg_month_idx 12];
            end


        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Create all variables and default values


            % Define default values for all controls
            app.ChkRomanPc.Value = true;

            app.JanuaryCheckBox.Value = true;
            app.AprilCheckBox.Value = true;
            app.JulyCheckBox.Value = true;
            app.OctoberCheckBox.Value = true;

            % update after setting default values
            UpdateAllVariables(app)

        end

        % Button pushed function: StartOptimizationButton
        function StartOptimizationButtonPushed(app, event)
            
            UpdateAllVariables(app)


            %app.OutputInfoTextArea.Value = strjoin(string(app.MonthIDX));

            if isempty(app.cfg_month_idx)

                % show message???

            else

                playground_0CF_UI;


                %playGroundFunction(app.cfg_roman_pc);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartOptimizationButton = uibutton(app.UIFigure, 'push');
            app.StartOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartOptimizationButtonPushed, true);
            app.StartOptimizationButton.Text = 'Start Optimization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AppEnvironmentTab = uitab(app.TabGroup);
            app.AppEnvironmentTab.Title = 'App Environment';
            app.ChkRomanPc = uicheckbox(app.AppEnvironmentTab);
            app.ChkRomanPc.Text = 'Running on Roman''s PC';
            app.DataFilteringTab = uitab(app.TabGroup);
            app.DataFilteringTab.Title = 'Data Filtering';
            app.SelectwhichmonthstoincludeinthecalculationLabel = uilabel(app.DataFilteringTab);
            app.SelectwhichmonthstoincludeinthecalculationLabel.Text = 'Select which months to include in the calculation:';
            app.JanuaryCheckBox = uicheckbox(app.DataFilteringTab);
            app.JanuaryCheckBox.Text = 'January';
            app.FebruaryCheckBox = uicheckbox(app.DataFilteringTab);
            app.FebruaryCheckBox.Text = 'February';
            app.MarchCheckBox = uicheckbox(app.DataFilteringTab);
            app.MarchCheckBox.Text = 'March';
            app.AprilCheckBox = uicheckbox(app.DataFilteringTab);
            app.AprilCheckBox.Text = 'April';
            app.MayCheckBox = uicheckbox(app.DataFilteringTab);
            app.MayCheckBox.Text = 'May';
            app.JuneCheckBox = uicheckbox(app.DataFilteringTab);
            app.JuneCheckBox.Text = 'June';
            app.JulyCheckBox = uicheckbox(app.DataFilteringTab);
            app.JulyCheckBox.Text = 'July';
            app.AugustCheckBox = uicheckbox(app.DataFilteringTab);
            app.AugustCheckBox.Text = 'August';
            app.SeptemberCheckBox = uicheckbox(app.DataFilteringTab);
            app.SeptemberCheckBox.Text = 'September';
            app.OctoberCheckBox = uicheckbox(app.DataFilteringTab);
            app.OctoberCheckBox.Text = 'October';
            app.NovemberCheckBox = uicheckbox(app.DataFilteringTab);
            app.NovemberCheckBox.Text = 'November';
            app.DecemberCheckBox = uicheckbox(app.DataFilteringTab);
            app.DecemberCheckBox.Text = 'December';
            app.TechnologiesConfigurationTab = uitab(app.TabGroup);
            app.TechnologiesConfigurationTab.Title = 'Technologies Configuration';
            app.OutputInfoTextAreaLabel = uilabel(app.UIFigure);
            app.OutputInfoTextAreaLabel.Text = 'Output Info';
            app.OutputInfoTextArea = uitextarea(app.UIFigure);
            app.ParameterConfigurationLabel = uilabel(app.UIFigure);
            app.ParameterConfigurationLabel.Text = 'Parameter Configuration';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.Text = 'Load Data';
            app.UCIDataLoadedLampLabel = uilabel(app.UIFigure);
            app.UCIDataLoadedLampLabel.Text = 'UCI Data Loaded';
            app.UCIDataLoadedLamp = uilamp(app.UIFigure);
            app.OutputDataAndGraphs = uitabgroup(app.UIFigure);
            app.OutputDataAndGraphs.Visible = 'off';
            app.Tab = uitab(app.OutputDataAndGraphs);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.OutputDataAndGraphs);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end